classdef HMAlpha < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        HypotonyMaculopathyAlphaLabel  matlab.ui.control.Label
        yScaleEditField                matlab.ui.control.NumericEditField
        yScaleEditFieldLabel           matlab.ui.control.Label
        xScaleEditField                matlab.ui.control.NumericEditField
        xScaleEditFieldLabel           matlab.ui.control.Label
        ImageLabel                     matlab.ui.control.Label
        FlaggedLabel                   matlab.ui.control.Label
        ResultLabel                    matlab.ui.control.Label
        RunAnalysisButton              matlab.ui.control.Button
        PathLabel                      matlab.ui.control.Label
        ROIButton                      matlab.ui.control.Button
        FlagImageButton                matlab.ui.control.Button
        PreviousButton                 matlab.ui.control.Button
        NextButton                     matlab.ui.control.Button
        UIAxes                         matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        imageFolder % Description
        thisImage
        imageFiles
        imageNumber
        ROI
        flagged
        alphaVolumeBM
        alphaVolumeRet
        xScale
        yScale
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.imageFolder=uigetdir('/Users/santiago/Dropbox (Biophotonics)/Projects/Remy/Hypotony/Data');
            app.imageFiles=[dir(fullfile(app.imageFolder, '*.tif')); dir(fullfile(app.imageFolder, '*.png'))];
            %% exit if no files found
            if numel(app.imageFiles)==0
                uialert(app.UIFigure, 'Software only accepts TIF and PNG files', 'File not found',...
                    'Icon','error');
            end
            %% open images
            app.thisImage=imread(fullfile(app.imageFiles(1).folder, app.imageFiles(1).name));
            imshow(app.thisImage, 'Parent', app.UIAxes);
            app.imageNumber=1;

            app.xScaleEditField.Value=5.7;
            app.xScale=app.xScaleEditField.Value;
            app.yScaleEditField.Value=3.9;
            app.yScale=app.yScaleEditField.Value;
            
            %check whether previous analysis exists
            if isfile(fullfile(app.imageFolder, 'ROI.mat'))
%                 fig = uifigure;
                selection=uiconfirm(app.UIFigure, 'Use previous ROI and flaggeg images?', 'Previous analysis exists',...
                    'Options', {'Yes', 'No'}, 'Icon','warning');
                switch selection
                    case 'Yes'
                        load(fullfile(app.imageFolder, 'ROI.mat'));
                        app.ROI=ROI;
                        rectangle(app.UIAxes, 'Position', round(app.ROI), 'EdgeColor', 'r', 'LineWidth',2);
                        if isfile(fullfile(app.imageFolder, 'flagged.mat'))
                            load(fullfile(app.imageFolder, 'flagged.mat'));
                            app.flagged=flagged;
                            app.FlaggedLabel.Text=['Flagged images: ' num2str(flagged)];
                        end
                    case 'No'
                end
            end
            app.PathLabel.Text = app.imageFolder;
            app.ImageLabel.Text=['Image ' num2str(app.imageNumber) ' of ' num2str(size(app.imageFiles, 1))];
        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            if app.imageNumber~=size(app.imageFiles, 1)
                app.imageNumber=app.imageNumber+1;
                app.thisImage=imread(fullfile(app.imageFiles(app.imageNumber).folder,...
                    app.imageFiles(app.imageNumber).name))
                imshow(app.thisImage, 'Parent', app.UIAxes);
            end
            if size(app.ROI, 2)>1
%                 app.ROI
                rectangle(app.UIAxes, 'Position', round(app.ROI), 'EdgeColor', 'r', 'LineWidth',2)
            end
            app.ImageLabel.Text=['Image ' num2str(app.imageNumber) ' of ' num2str(size(app.imageFiles, 1))];
        end

        % Button pushed function: PreviousButton
        function PreviousButtonPushed(app, event)
            if app.imageNumber~=1
                app.imageNumber=app.imageNumber-1;
                app.thisImage=imread(fullfile(app.imageFiles(app.imageNumber).folder,...
                    app.imageFiles(app.imageNumber).name));
                imshow(app.thisImage, 'Parent', app.UIAxes);
            end
            if size(app.ROI, 2)>1
                app.ROI;
                rectangle(app.UIAxes, 'Position', round(app.ROI), 'EdgeColor', 'r', 'LineWidth',2);
            end
            app.ImageLabel.Text=['Image ' num2str(app.imageNumber) ' of ' num2str(size(app.imageFiles, 1))];
        end

        % Button pushed function: ROIButton
        function ROIButtonPushed(app, event)
            im2crop=imshow(app.thisImage, 'Parent', app.UIAxes);
            [~,app.ROI] = imcrop(im2crop);
            rectangle(app.UIAxes, 'Position', round(app.ROI), 'EdgeColor', 'r', 'LineWidth',2);
            ROI=round(app.ROI);
            save(fullfile(app.imageFiles(app.imageNumber).folder, 'ROI.mat'), 'ROI');
        end

        % Button pushed function: FlagImageButton
        function FlagImageButtonPushed(app, event)
            app.flagged=[app.flagged app.imageNumber];
            flagged=app.flagged;
            save(fullfile(app.imageFiles(1).folder, 'flagged.mat'), 'flagged');
            app.FlaggedLabel.Text=['Flagged images: ' num2str(flagged)];
        end

        % Button pushed function: RunAnalysisButton
        function RunAnalysisButtonPushed(app, event)
            app.RunAnalysisButton.Text='Running...';
            app.RunAnalysisButton.Enable=false;
            app.imageFiles(app.flagged)=[];
            analyzeBscans(app.imageFiles, [app.xScale, app.yScale]);
            [app.alphaVolumeBM, app.alphaVolumeRet]=analyzeVolume(app.imageFiles, [app.xScale, app.yScale]);
            app.RunAnalysisButton.Enable=true;
            app.RunAnalysisButton.Text='Run Analysis';
            app.ResultLabel.Text=['BM Alpha Volume=' num2str(app.alphaVolumeBM)...
                ' Ret Alpha Volume=' num2str(app.alphaVolumeRet)];
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.NextButton = uibutton(app.UIFigure, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Text = 'Next';
            app.PreviousButton = uibutton(app.UIFigure, 'push');
            app.PreviousButton.ButtonPushedFcn = createCallbackFcn(app, @PreviousButtonPushed, true);
            app.PreviousButton.Text = 'Previous';
            app.FlagImageButton = uibutton(app.UIFigure, 'push');
            app.FlagImageButton.ButtonPushedFcn = createCallbackFcn(app, @FlagImageButtonPushed, true);
            app.FlagImageButton.Text = 'Flag Image';
            app.ROIButton = uibutton(app.UIFigure, 'push');
            app.ROIButton.ButtonPushedFcn = createCallbackFcn(app, @ROIButtonPushed, true);
            app.ROIButton.Text = 'ROI';
            app.PathLabel = uilabel(app.UIFigure);
            app.PathLabel.Text = 'Path:';
            app.RunAnalysisButton = uibutton(app.UIFigure, 'push');
            app.RunAnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @RunAnalysisButtonPushed, true);
            app.RunAnalysisButton.Text = 'Run Analysis';
            app.ResultLabel = uilabel(app.UIFigure);
            app.ResultLabel.Text = 'Result';
            app.FlaggedLabel = uilabel(app.UIFigure);
            app.FlaggedLabel.Text = 'Flagged:';
            app.ImageLabel = uilabel(app.UIFigure);
            app.ImageLabel.Text = 'Image';
            app.xScaleEditFieldLabel = uilabel(app.UIFigure);
            app.xScaleEditFieldLabel.Text = 'x Scale';
            app.xScaleEditField = uieditfield(app.UIFigure, 'numeric');
            app.yScaleEditFieldLabel = uilabel(app.UIFigure);
            app.yScaleEditFieldLabel.Text = 'y Scale';
            app.yScaleEditField = uieditfield(app.UIFigure, 'numeric');
            app.HypotonyMaculopathyAlphaLabel = uilabel(app.UIFigure);
            app.HypotonyMaculopathyAlphaLabel.Text = 'Hypotony Maculopathy Alpha';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HMAlpha

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end